classdef CALC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DeleteButton                matlab.ui.control.Button
        EnterButton                 matlab.ui.control.Button
        Label_6                     matlab.ui.control.Label
        PlotingButtonGroup          matlab.ui.container.ButtonGroup
        equationButton              matlab.ui.control.RadioButton
        cosButton                   matlab.ui.control.RadioButton
        sineButton                  matlab.ui.control.RadioButton
        resultButton                matlab.ui.control.RadioButton
        xyaxisButton                matlab.ui.control.RadioButton
        yaxisButton                 matlab.ui.control.RadioButton
        xaxisButton                 matlab.ui.control.RadioButton
        Label_4                     matlab.ui.control.Label
        ErrorConsoleLabel_2         matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        resultLabel                 matlab.ui.control.Label
        EqualButton_2               matlab.ui.control.Button
        ListBox_2                   matlab.ui.control.ListBox
        EditField_5                 matlab.ui.control.EditField
        y_axisLabel                 matlab.ui.control.Label
        EditField_4                 matlab.ui.control.EditField
        x_axisLabel                 matlab.ui.control.Label
        EditField_3                 matlab.ui.control.EditField
        Label3                      matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        EqualButton                 matlab.ui.control.Button
        Label_5                     matlab.ui.control.Label
        ErrorConsoleLabel           matlab.ui.control.Label
        ArithmaticOperationsLabel   matlab.ui.control.Label
        ListBox                     matlab.ui.control.ListBox
        Label                       matlab.ui.control.Label
        EditField_2                 matlab.ui.control.NumericEditField
        EditField                   matlab.ui.control.NumericEditField
        EnterequationfunxoryxLabel  matlab.ui.control.Label
        SimpleCalculatorLabel       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, x)
            global num
            num = 0;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            app.Label.Text = value;
        end

        % Button pushed function: EqualButton
        function EqualButtonPushed(app, event)
            num1 = app.EditField.Value;
            num2 = app.EditField_2.Value;
            
            if(app.Label.Text == '/')
                if((num2 == 0))
                    app.Label_5.Text = "Division by zero";
                    res = 0;
                else
                    res = num1 / num2;
                end
            elseif(app.Label.Text == '+')
                res = num1 + num2;
                app.Label_5.Text = "";
            elseif(app.Label.Text == '-')
                res = num1 - num2;
                app.Label_5.Text = "";
            elseif(app.Label.Text == '*')
                res = num1 * num2;
                app.Label_5.Text = "";
            end
            
            app.Label3.Text = num2str(res);
        end

        % Button pushed function: EqualButton_2
        function EqualButton_2Pushed(app, event)
            arr1 = app.EditField_4.Value;
            arr2 = app.EditField_5.Value;
           
            res1 = 0;
            res2 = 0;
            res3 = 0;
            
            if(app.Label_6.Text == '/')
                if(arr2(2) == '0' || arr2(4) == '0' || arr2(6) == '0')
                    app.Label_4.Text = "Division by zero";
                    
                else
                    res1 = str2num(arr1(2)) / str2num(arr2(2));
                    res2 = str2num(arr1(4)) / str2num(arr2(4));
                    res3 = str2num(arr1(6)) / str2num(arr2(6));
                    
                end
            elseif(app.Label_6.Text == '+')
                    res1 = str2num(arr1(2)) + str2num(arr2(2));
                    res2 = str2num(arr1(4)) + str2num(arr2(4));
                    res3 = str2num(arr1(6)) + str2num(arr2(6));
            elseif(app.Label_6.Text == '-')
                    res1 = str2num(arr1(2)) - str2num(arr2(2));
                    res2 = str2num(arr1(4)) - str2num(arr2(4));
                    res3 = str2num(arr1(6)) - str2num(arr2(6));
             elseif(app.Label_6.Text == '*')
                    res1 = str2num(arr1(2)) * str2num(arr2(2));
                    res2 = str2num(arr1(4)) * str2num(arr2(4));
                    res3 = str2num(arr1(6)) * str2num(arr2(6));

            end
            
            app.Label_3.Text = ['[', num2str(res1),' ', num2str(res2), ' ', num2str(res3), ']'];

            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            app.Label_6.Text = value;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            x_axis = app.EditField_4.Value;
            y_axis = app.EditField_5.Value;
            res = app.Label_3.Text;
            vector_x = str2num(x_axis);
            vector_y = str2num(y_axis);
            vector_res = str2num(res);
            %res = extractBetween(res, '[', ']');
            %vector_res = str2double(split(res{1}));
            
            target = app.PlotingButtonGroup.SelectedObject.Text;
            
            if(target == "x axis")
               plot(app.UIAxes, vector_x);
            elseif(target == "y axis")
                plot(app.UIAxes, vector_y);
            elseif(target == "x y axis")
                plot(app.UIAxes, vector_x);
                hold(app.UIAxes, "on");
                plot(app.UIAxes, vector_y);
                hold(app.UIAxes, "off");
            elseif(target == "result")
                plot(app.UIAxes, vector_res);
            elseif(target == "sine")
                x = linspace(0, 2*pi, 30);
                y = sin(x);
                plot(app.UIAxes, x, y);
            elseif(target == "cos")
                x = linspace(0, 2*pi, 30);
                y = cos(x);
                plot(app.UIAxes, x, y);
            end
            
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.EnterequationfunxoryxLabel = uilabel(app.UIFigure);
            app.EnterequationfunxoryxLabel.Text = 'Enter equation fun(x) or y(x)';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 5;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Value = 2;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '+';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'+', '-', '*', '/'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '-';
            app.ArithmaticOperationsLabel = uilabel(app.UIFigure);
            app.ArithmaticOperationsLabel.Text = 'Arithmatic Operations';
            app.ErrorConsoleLabel = uilabel(app.UIFigure);
            app.ErrorConsoleLabel.Text = 'Error Console';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.ButtonPushedFcn = createCallbackFcn(app, @EqualButtonPushed, true);
            app.EqualButton.Text = 'Equal';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.x_axisLabel = uilabel(app.UIFigure);
            app.x_axisLabel.Text = 'x_axis';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.y_axisLabel = uilabel(app.UIFigure);
            app.y_axisLabel.Text = 'y_axis';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'+', '-', '*', '/'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = '-';
            app.EqualButton_2 = uibutton(app.UIFigure, 'push');
            app.EqualButton_2.ButtonPushedFcn = createCallbackFcn(app, @EqualButton_2Pushed, true);
            app.EqualButton_2.Text = 'Equal';
            app.resultLabel = uilabel(app.UIFigure);
            app.resultLabel.Text = 'result';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.ErrorConsoleLabel_2 = uilabel(app.UIFigure);
            app.ErrorConsoleLabel_2.Text = 'Error Console';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.PlotingButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotingButtonGroup.Title = 'Ploting';
            app.xaxisButton = uiradiobutton(app.PlotingButtonGroup);
            app.xaxisButton.Text = 'x axis';
            app.xaxisButton.Value = true;
            app.yaxisButton = uiradiobutton(app.PlotingButtonGroup);
            app.yaxisButton.Text = 'y axis';
            app.xyaxisButton = uiradiobutton(app.PlotingButtonGroup);
            app.xyaxisButton.Text = 'x y axis';
            app.resultButton = uiradiobutton(app.PlotingButtonGroup);
            app.resultButton.Text = 'result';
            app.sineButton = uiradiobutton(app.PlotingButtonGroup);
            app.sineButton.Text = 'sine';
            app.cosButton = uiradiobutton(app.PlotingButtonGroup);
            app.cosButton.Text = 'cos';
            app.equationButton = uiradiobutton(app.PlotingButtonGroup);
            app.equationButton.Text = 'equation';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '-';
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CALC(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end